classdef LanguageView < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        SalvarButton              matlab.ui.control.Button
        GridLayout4               matlab.ui.container.GridLayout
        AgregarButton             matlab.ui.control.Button
        GridLayout3               matlab.ui.container.GridLayout
        EditField                 matlab.ui.control.EditField
        CaractrLabel              matlab.ui.control.Label
        EliminarButton            matlab.ui.control.Button
        CaracterspermitidosLabel  matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
    end

    properties (Access = public)
        Language
        figure
    end
    
    
    methods (Access = public)
        
        function init(comp,Language,figure)
            comp.figure=figure;
            comp.Language=Language;
           comp.ListBox.Items=comp.Language.Alphabet;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(comp, event)
           comp.ListBox.Items(ismember(comp.ListBox.Items,comp.ListBox.Value)) = [];
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(comp, event)
            letter=upper(strtrim(comp.EditField.Value));
            s=size(letter,2);
            if(s~=1||any(strcmp(comp.ListBox.Items,letter)))
                return;
            end
            A=comp.ListBox.Items;
            A{end+1}=letter;
            disp(A);
            comp.ListBox.Items=A;
            comp.EditField.Value='';
        end

        % Button pushed function: SalvarButton
        function SalvarButtonPushed(comp, event)
            comp.Language.Alphabet=comp.ListBox.Items;
            comp.Language.Save();
            comp.figure.Reinit();
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [0 0 505 361];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.ListBox = uilistbox(comp.GridLayout);
            comp.ListBox.Items = {};
            comp.ListBox.Value = {};
            comp.CaracterspermitidosLabel = uilabel(comp.GridLayout);
            comp.CaracterspermitidosLabel.Text = 'Caracterés permitidos';
            comp.GridLayout4 = uigridlayout(comp.GridLayout);
            comp.EliminarButton = uibutton(comp.GridLayout4, 'push');
            comp.EliminarButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @EliminarButtonPushed, true);
            comp.EliminarButton.Text = 'Eliminar';
            comp.GridLayout3 = uigridlayout(comp.GridLayout4);
            comp.CaractrLabel = uilabel(comp.GridLayout3);
            comp.CaractrLabel.Text = 'Caractér:';
            comp.EditField = uieditfield(comp.GridLayout3, 'text');
            comp.AgregarButton = uibutton(comp.GridLayout4, 'push');
            comp.AgregarButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AgregarButtonPushed, true);
            comp.AgregarButton.Text = 'Agregar';
            comp.SalvarButton = uibutton(comp.GridLayout, 'push');
            comp.SalvarButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SalvarButtonPushed, true);
            comp.SalvarButton.Text = 'Salvar';
        end
    end
end