classdef NumericKeyView < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout5        matlab.ui.container.GridLayout
        LlaveSpinner       matlab.ui.control.Spinner
        LlaveSpinnerLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        cipher
        language
    end
    
    
    methods (Access = public)       
        function key = GetKey(comp)
            key=comp.LlaveSpinner.Value;
        end

        function validateKey(comp,language,cipher)    
            comp.language=language;
            comp.cipher=cipher;
            key = comp.LlaveEditField.Value;
            key = cipher.validateKey(key);
            comp.LlaveEditField.Value=key;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LlaveSpinner
        function LlaveSpinnerValueChanged(comp, event)
            value = comp.validateKey(comp.language,comp.cipher);
            comp.LlaveSpinner.Value=value;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [0 0 320 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout5 = uigridlayout(comp);
            comp.LlaveSpinnerLabel = uilabel(comp.GridLayout5);
            comp.LlaveSpinnerLabel.Text = 'Llave:';
            comp.LlaveSpinner = uispinner(comp.GridLayout5);
            comp.LlaveSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LlaveSpinnerValueChanged, true);
            comp.LlaveSpinner.Value = 1;
        end
    end
end