classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CifradoCesarUIFigure          matlab.ui.Figure
        ArchivoMenu                   matlab.ui.container.Menu
        AbrirMenu                     matlab.ui.container.Menu
        IdiomasMenu                   matlab.ui.container.Menu
        AcercadeMenu                  matlab.ui.container.Menu
        GridLayout3                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        CifradoTab                    matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        GridLayout_2                  matlab.ui.container.GridLayout
        TextocifradoLabel             matlab.ui.control.Label
        TextoTextArea                 matlab.ui.control.TextArea
        GridLayout                    matlab.ui.container.GridLayout
        TextoplanoLabel               matlab.ui.control.Label
        TextoclaroTextArea            matlab.ui.control.TextArea
        HistogramaTab                 matlab.ui.container.Tab
        GridLayout10                  matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        FiltrarLabel                  matlab.ui.control.Label
        TextocifradoCheckBox          matlab.ui.control.CheckBox
        LenguajeCheckBox              matlab.ui.control.CheckBox
        TextoplanoCheckBox            matlab.ui.control.CheckBox
        GridLayout7                   matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        IdiomaDropDown                matlab.ui.control.DropDown
        IdiomaDropDownLabel           matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        LlavededesplazamientoSpinner  matlab.ui.control.Spinner
        LlavededesplazamientoSpinnerLabel  matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        DescifrarButton               matlab.ui.control.Button
        LimpiarButton                 matlab.ui.control.Button
        CifrarButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Help=helper %instancia un objeto de la clase Helper que esta en el archivo Helper.m
        fileReader;
        LanguagesMgr;
        Language;
        cesar;
    end
    
    methods (Access = public)        
        function init(app)
            clc
            app.LanguagesMgr=LanguagesManager;
            items=[];
            itemsData=[];

            for i=1:size(app.LanguagesMgr.Languages,1)
                lang=app.LanguagesMgr.Languages(i);
                items{end+1}=lang.Name;
                itemsData{end+1}=lang;
            end
            app.IdiomaDropDown.Items=items;
            app.IdiomaDropDown.ItemsData=itemsData;

            app.Language=app.LanguagesMgr.Languages(1);
            app.IdiomaDropDown.Value=app.Language;
            app.cesar=CesarCipher(app.Language);
            app.plot();
        end
        
        function plot(app)
            pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
            pgrssbar.show('Graficando ...');
            app.deletePlot();
            legends={};
            ys={};
            langPlot=app.Language.FrequencyPlot();

            if(app.LenguajeCheckBox.Value)                
                legends=[legends,'Idioma'];
                ys=[ys,langPlot.y];
            end

            if(app.TextoplanoCheckBox.Value)
                cleanPlot=app.cesar.PlotFrequency(app.TextoclaroTextArea.Value);
                legends=[legends,'Texto plano'];
                ys=[ys,cleanPlot.y];
            end
            
            if(app.TextocifradoCheckBox.Value)
                chiperPlot=app.cesar.PlotFrequency(app.TextoTextArea.Value);
                legends=[legends,'Texto cifrado'];
                ys=[ys,chiperPlot.y];
            end
        
            plotData=PlotData(langPlot.x,ys);
            plotData.XTickLabels=langPlot.XTickLabels;
            plotData.Title='Comparativa de frecuencias';
            plotData.Xlabel='Caractér';
            plotData.Ylabel='Frecuencia';
            plotData.Legends=legends;
            histogram=HistogramView(app.GridLayout7);
            histogram.plotData(plotData);
            pgrssbar.close();
        end

        function deletePlot(app)
            a=app.GridLayout7.Children;
            delete(a);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Menu selected function: AbrirMenu
        function AbrirMenuSelected(app, event)
            %Mostrar una ventana para seleccionar archivo filtrado .txt
            [file,path] = uigetfile('*.txt');
            %Si se selecciono un archivo
            if ~(isequal(file,0))
                %Obtener ruta completa del archivo seleccionado
                fullpath=fullfile(path,file);

                %instancia un objeto de la clase ProgressBar que esta en el
                %archivo ProgressBar.m
                pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
                %Mostrar barra de progeso
                pgrssbar.show('Leyendo archivo...');
                app.fileReader=FileReader(fullpath);
                data=app.fileReader.readFileAndFilter(app.Language);
                %Concatenar lineas en una string de texto y mostrarla en
                %el text area y limpiar el textarea 
                app.TextoTextArea.Value='';
                app.TextoclaroTextArea.Value=string(data);               
                pgrssbar.close();
            end            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
             %limpiar los textareas
             app.TextoclaroTextArea.Value=''; 
             app.TextoTextArea.Value='';
        end

        % Button pushed function: DescifrarButton
        function DescifrarButtonPushed(app, event)
            %Obtener el desplazamiento de la llave del control spinner
            keyOffset=app.LlavededesplazamientoSpinner.Value;
            
            %instancia un objeto de la clase ProgressBar que esta en el
            %archivo ProgressBar.m
            pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
            %Mostrar barra de progeso
            pgrssbar.show('Descifrando texto...');
            %asigna el valor del textarea decifrado al resultado de la función
            %'dechiper' que se encarga de decifrar el texto del textarea
            %cifrado y le envia el offset de la llave seleccionada
            app.TextoclaroTextArea.Value=app.cesar.decipher(app.TextoTextArea.Value,keyOffset);
            app.plot();
            pgrssbar.close();
        end

        % Button pushed function: CifrarButton
        function CifrarButtonPushed(app, event)
           %Obtener el desplazamiento de la llave del control spinner
           keyOffset=app.LlavededesplazamientoSpinner.Value;
           
           %instancia un objeto de la clase ProgressBar que esta en el
           %archivo ProgressBar.m
           pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
           %Mostrar barra de progeso
           pgrssbar.show('Cifrando texto...');
           %asigna el valor del textarea cifrado al resultado de la función
           %'chiper' que se encarga de cifrar el texto del textarea
           %decifrado y le envia el offset de la llave seleccionada
           plainText=app.TextoclaroTextArea.Value;
           result=app.cesar.cipher(plainText,keyOffset);
           app.TextoclaroTextArea.Value=result(1);
           app.TextoTextArea.Value=string(result(2));
           app.plot();
           pgrssbar.close();           
        end

        % Menu selected function: AcercadeMenu
        function AcercadeMenuSelected(app, event)
            app.Help.centerWindow(about,app.CifradoCesarUIFigure);
        end

        % Menu selected function: IdiomasMenu
        function IdiomasMenuSelected(app, event)
            view=LanguagesView;   
            view.init(app.LanguagesMgr,app);
        end

        % Value changed function: IdiomaDropDown
        function IdiomaDropDownValueChanged(app, event)
            value = app.IdiomaDropDown.Value;
            app.Language=value;
            %instancia un objeto de la clase CesarChiper que esta en el
            %archivo CesarChiper.m
            app.cesar=CesarCipher(app.Language);
            app.plot();
        end

        % Value changed function: LlavededesplazamientoSpinner
        function LlavededesplazamientoSpinnerValueChanged(app, event)
            value = app.LlavededesplazamientoSpinner.Value;
            if(value>=size(app.Language.Alphabet,2))
                msgbox("La llave debe ser menor que el tamaño del diccionario.","Error","error","modal");
                app.LlavededesplazamientoSpinner.Value=event.PreviousValue;
                return;
            end
            if(value<=0)
                msgbox("La llave debe ser mayor que cero.","Error","error","modal");
                app.LlavededesplazamientoSpinner.Value=event.PreviousValue;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextoTextArea.Value=app.TextoclaroTextArea.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TextoclaroTextArea.Value=app.TextoTextArea.Value;
        end

        % Value changed function: LenguajeCheckBox, TextocifradoCheckBox, 
        % ...and 1 other component
        function LenguajeCheckBoxValueChanged(app, event)
            app.plot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CifradoCesarUIFigure = uifigure('Visible', 'off');
            app.CifradoCesarUIFigure.Name = 'Cifrado Cesar';
            app.ArchivoMenu = uimenu(app.CifradoCesarUIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.AbrirMenu = uimenu(app.ArchivoMenu);
            app.AbrirMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirMenuSelected, true);
            app.AbrirMenu.Text = 'Abrir';
            app.IdiomasMenu = uimenu(app.CifradoCesarUIFigure);
            app.IdiomasMenu.MenuSelectedFcn = createCallbackFcn(app, @IdiomasMenuSelected, true);
            app.IdiomasMenu.Text = 'Idiomas';
            app.AcercadeMenu = uimenu(app.CifradoCesarUIFigure);
            app.AcercadeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeMenuSelected, true);
            app.AcercadeMenu.Text = 'Acerca de...';
            app.GridLayout3 = uigridlayout(app.CifradoCesarUIFigure);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.CifrarButton = uibutton(app.GridLayout4, 'push');
            app.CifrarButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarButtonPushed, true);
            app.CifrarButton.Text = 'Cifrar';
            app.LimpiarButton = uibutton(app.GridLayout4, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.DescifrarButton = uibutton(app.GridLayout4, 'push');
            app.DescifrarButton.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButtonPushed, true);
            app.DescifrarButton.Text = 'Descifrar';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.LlavededesplazamientoSpinnerLabel = uilabel(app.GridLayout5);
            app.LlavededesplazamientoSpinnerLabel.Text = 'Llave de desplazamiento:';
            app.LlavededesplazamientoSpinner = uispinner(app.GridLayout5);
            app.LlavededesplazamientoSpinner.ValueChangedFcn = createCallbackFcn(app, @LlavededesplazamientoSpinnerValueChanged, true);
            app.LlavededesplazamientoSpinner.Value = 1;
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.IdiomaDropDownLabel = uilabel(app.GridLayout6);
            app.IdiomaDropDownLabel.Text = 'Idioma:';
            app.IdiomaDropDown = uidropdown(app.GridLayout6);
            app.IdiomaDropDown.Items = {};
            app.IdiomaDropDown.ValueChangedFcn = createCallbackFcn(app, @IdiomaDropDownValueChanged, true);
            app.IdiomaDropDown.Value = {};
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.CifradoTab = uitab(app.TabGroup);
            app.CifradoTab.Title = 'Cifrado';
            app.GridLayout8 = uigridlayout(app.CifradoTab);
            app.GridLayout = uigridlayout(app.GridLayout8);
            app.TextoclaroTextArea = uitextarea(app.GridLayout);
            app.TextoplanoLabel = uilabel(app.GridLayout);
            app.TextoplanoLabel.Text = 'Texto plano';
            app.GridLayout_2 = uigridlayout(app.GridLayout8);
            app.TextoTextArea = uitextarea(app.GridLayout_2);
            app.TextocifradoLabel = uilabel(app.GridLayout_2);
            app.TextocifradoLabel.Text = 'Texto cifrado';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.Button = uibutton(app.GridLayout9, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.GridLayout9, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.HistogramaTab = uitab(app.TabGroup);
            app.HistogramaTab.Title = 'Histograma';
            app.GridLayout10 = uigridlayout(app.HistogramaTab);
            app.GridLayout7 = uigridlayout(app.GridLayout10);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.TextoplanoCheckBox = uicheckbox(app.GridLayout11);
            app.TextoplanoCheckBox.ValueChangedFcn = createCallbackFcn(app, @LenguajeCheckBoxValueChanged, true);
            app.TextoplanoCheckBox.Text = 'Texto plano';
            app.LenguajeCheckBox = uicheckbox(app.GridLayout11);
            app.LenguajeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LenguajeCheckBoxValueChanged, true);
            app.LenguajeCheckBox.Text = 'Lenguaje';
            app.LenguajeCheckBox.Value = true;
            app.TextocifradoCheckBox = uicheckbox(app.GridLayout11);
            app.TextocifradoCheckBox.ValueChangedFcn = createCallbackFcn(app, @LenguajeCheckBoxValueChanged, true);
            app.TextocifradoCheckBox.Text = 'Texto cifrado';
            app.FiltrarLabel = uilabel(app.GridLayout11);
            app.FiltrarLabel.Text = 'Filtrar:';
            % Show the figure after all components are created
            app.CifradoCesarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CifradoCesarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CifradoCesarUIFigure)
        end
    end
end